classdef solar_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        IdealTempFEditField       matlab.ui.control.NumericEditField
        IdealTempFEditFieldLabel  matlab.ui.control.Label
        CItyDropDown              matlab.ui.control.DropDown
        CItyDropDownLabel         matlab.ui.control.Label
        DayEditField              matlab.ui.control.NumericEditField
        DayEditFieldLabel         matlab.ui.control.Label
        MonthEditField            matlab.ui.control.NumericEditField
        MonthEditFieldLabel       matlab.ui.control.Label
        YearEditField             matlab.ui.control.NumericEditField
        YearEditFieldLabel        matlab.ui.control.Label
        MovingDropDown            matlab.ui.control.DropDown
        MovingDropDownLabel       matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        TimeSlider                matlab.ui.control.Slider
        TimeSliderLabel           matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        AngleEditField            matlab.ui.control.EditField
        AngleEditFieldLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.CItyDropDown.Value;
            app.CItyDropDown.Items = importdata('Cities.txt').textdata(:,1);
        end

        % Value changed function: MovingDropDown
        function MovingDropDownValueChanged(app, event)
            value = app.MovingDropDown.Value;
            if (strcmpi(value,'Stationary'))
                app.MonthEditField.Enable = 'Off';
                app.DayEditField.Enable = 'Off';
                app.IdealTempFEditField.Enable = 'On';
            elseif (strcmpi(value,'Movable'))
                app.DayEditField.Enable='On';
                app.MonthEditField.Enable= 'On';
                app.IdealTempFEditField.Enable = 'Off';
            end
            
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            if (strcmpi(app.MovingDropDown.Value,'Movable'))
                value = app.MonthEditField.Value;
            elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                value = month(app.DayEditField.Value);
            end   
                
            
        end

        % Value changed function: CItyDropDown
        function CItyDropDownValueChanged(app, event)

            app.CItyDropDown.Items = importdata('Cities.txt').textdata(:,1);
            value = app.CItyDropDown.Value;
        end

        % Value changing function: AngleEditField
        function AngleEditFieldValueChanging(app, event)
                
% t = datetime(app.YearEditField.Value,app.MonthEditField.Value,app.DayEditField.Value);
% 
% latlong=importdata('C:\Users\alexa\Documents\Solar Controller\Cities.txt').data;
% idx_city = find(app.CItyDropDown.Value == app.CItyDropDown.Items);
% lat = latlong(idx_city,1);
% longitude = latlong(idx_city,2);
% timezone = latlong(idx_city,3);
%             
% d = day(t, 'dayofyear');
% if mod(year, 4) == 0
%     day_total = 366;
% else
%     day_total = 365;
% end
% hour = 0:23;
% gamma = zeros(1,length(hour)-1);
% for i = 1:length(hour)
%     gamma(i)=2.*pi/day_total.*(d-1+((hour(i)-12)/24));
% end
% 
% eqtime = 229.18*(0.000075 + 0.001868*cos(gamma) - 0.032077*sin(gamma) - 0.014615*cos(2*gamma) - 0.040849*sin(2*gamma));
% decl = 0.006918 - 0.399912*cos(gamma) + 0.070257*sin(gamma) - 0.006758*cos(2*gamma) + 0.000907*sin(2*gamma) - 0.002697*cos(3*gamma) + 0.00148*sin(3*gamma);
% 
% time_offset = eqtime + 4*longitude - 60*timezone;
% tst = hour*60 + time_offset;
% ha = (tst / 4) - 180;
% 
% zenith = acos(sind(lat).*sin(decl) + cosd(lat).*cos(decl).*cosd(ha));
% zenith=rad2deg(zenith);
% 
% elevation = 90-zenith;
% awningset = elevation-90;
% event.Value = awningset;
% changingValue = event.Value;
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            if (strcmpi(app.MovingDropDown.Value,'Movable'))
                value = app.DayEditField.Value;
            elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                string_cit = str(app.CItyDropDown.Value);
                temp = importdata(strcat(string_cit,'.txt')).textdata;
                temphigh = temp(:,2);
                idxtemp = find(temphigh>=app.IdealTempFEditField);
                app.DayEditField.Value = idxtemp(1);
            end
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            %value = app.AngleEditField.Value;
            app.AngleEditField.Enable = 'Off';
            t = datetime(app.YearEditField.Value,app.MonthEditField.Value,app.DayEditField.Value);

latlong=importdata('Cities.txt').data;
idx_city = find(strcmp(app.CItyDropDown.Items, app.CItyDropDown.Value));


lat = latlong(idx_city,1);
longitude = latlong(idx_city,2);
timezone = latlong(idx_city,3);
            
d = day(t, 'dayofyear');
if mod(app.YearEditField.Value, 4) == 0
    day_total = 366;
else
    day_total = 365;
end
hour = 0:23;
gamma = zeros(1,length(hour)-1);
for i = 1:length(hour)
    gamma(i)=2.*pi/day_total.*(d-1+((hour(i)-12)/24));
end

eqtime = 229.18*(0.000075 + 0.001868*cos(gamma) - 0.032077*sin(gamma) - 0.014615*cos(2*gamma) - 0.040849*sin(2*gamma));
decl = 0.006918 - 0.399912*cos(gamma) + 0.070257*sin(gamma) - 0.006758*cos(2*gamma) + 0.000907*sin(2*gamma) - 0.002697*cos(3*gamma) + 0.00148*sin(3*gamma);

time_offset = eqtime + 4*longitude - 60*timezone;
tst = hour*60 + time_offset;
ha = (tst / 4) - 180;

zenith = acos(sind(lat).*sin(decl) + cosd(lat).*cos(decl).*cosd(ha));
zenith=rad2deg(zenith);

%elevation = 90-zenith;
%awningset = elevation-90;
%app.AngleEditField.Value = num2str(awningset);
%app.AngleEditField.Value = sprintf('%.3f',app.AngleEditField);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.AngleEditField.Enable = 'Off';

if (strcmpi(app.MovingDropDown.Value,'Movable'))
                %value = app.DayEditField.Value;
                t = datetime(app.YearEditField.Value,app.MonthEditField.Value,app.DayEditField.Value);
                d = day(t, 'dayofyear');
elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                string_cit = app.CItyDropDown.Value;
                temp = importdata(strcat(string_cit,'.txt')).data;
                temphigh = temp(:,1);
                idxtemp = find(temphigh>=app.IdealTempFEditField.Value);
                d = idxtemp(1,1);
                if (mod(app.YearEditField.Value, 4) ==0 || d < 61 )
                    app.DayEditField.Value = day(d);
                else
                    app.DayEditField.Value = day(d)+1;
                end
end
if (strcmpi(app.MovingDropDown.Value,'Movable'))
                %value = app.MonthEditField.Value;
elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                app.MonthEditField.Value = month(d);
end   


t = datetime(app.YearEditField.Value,app.MonthEditField.Value,app.DayEditField.Value);

latlong=importdata('Cities.txt').data;
idx_city = find(strcmp(app.CItyDropDown.Items, app.CItyDropDown.Value));


lat = latlong(idx_city,1);
longitude = latlong(idx_city,2);
timezone = latlong(idx_city,3);
            
if mod(app.YearEditField.Value, 4) == 0
    day_total = 366;
else
    day_total = 365;
end
hour = 0:23;
gamma = zeros(1,length(hour));
for i = 1:length(hour)
    gamma(i)=2.*pi/day_total.*(d-1+((hour(i)-12)./24));
end

eqtime = 229.18*(0.000075 + 0.001868*cos(gamma) - 0.032077*sin(gamma) - 0.014615*cos(2*gamma) - 0.040849*sin(2*gamma));
decl = 0.006918 - 0.399912*cos(gamma) + 0.070257*sin(gamma) - 0.006758*cos(2*gamma) + 0.000907*sin(2*gamma) - 0.002697*cos(3*gamma) + 0.00148*sin(3*gamma);

time_offset = eqtime + 4*longitude - 60*timezone;
tst = hour*60 + time_offset;
ha = (tst / 4) - 180;

zenith = acos(sind(lat).*sin(decl) + cosd(lat).*cos(decl).*cosd(ha));
zenith=rad2deg(zenith);

elevation = 90-zenith;
suninterval = find(elevation>0);
% app.TimeSlider.Limits = [suninterval(1)-1,suninterval(length(suninterval))-1];
% app.TimeSlider.MajorTicks = (suninterval(1)-1:1:suninterval(length(suninterval))-1);
% app.TimeSlider.MajorTickLabels = {suninterval(1)-1:1:suninterval(length(suninterval))-1};

if (strcmpi(app.MovingDropDown.Value,'Movable'))
    awningset = elevation - 90;
    i = event.Value;
    app.AngleEditField.Value = num2str(awningset(i));      
elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
    elevationavg = mean(elevation(suninterval(1):suninterval(length(suninterval))));
    awningset = elevationavg-90;
    app.AngleEditField.Value = num2str(awningset);
end
% plot

%  angle = 90+awningset;
%  x = linspace(1,cosd(angle),20);
%  y = (sind(angle)-1)/(cosd(angle)-1)*x+2
% app.Gauge. = app.AngleEditField;
% plot(app.UIAxes,x,y);

angle = awningset;
 x = [0,cosd(angle)]
 y=[0,sind(angle)]
 %y = (sind(angle)-1)/(cosd(angle)-1)*x+2
%app.Gauge. = app.AngleEditField;
plot(app.UIAxes,x,y);
        end

        % Value changed function: IdealTempFEditField
        function IdealTempFEditFieldValueChanged(app, event)
            value = app.IdealTempFEditField.Value;
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
app.AngleEditField.Enable = 'Off';

            if (strcmpi(app.MovingDropDown.Value,'Movable'))
                %value = app.DayEditField.Value;
                t = datetime(app.YearEditField.Value,app.MonthEditField.Value,app.DayEditField.Value);
                d = day(t, 'dayofyear');
            elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                string_cit = app.CItyDropDown.Value;
                temp = importdata(strcat(string_cit,'.txt')).data;
                temphigh = temp(:,1);
                idxtemp = find(temphigh>=app.IdealTempFEditField.Value);
                d = idxtemp(1,1);
            
                if (mod(app.YearEditField.Value, 4) ==0 || d < 61 )
                    app.DayEditField.Value = day(d);
                else
                    app.DayEditField.Value = day(d)+1;
                end
            end
if (strcmpi(app.MovingDropDown.Value,'Movable'))
                %value = app.MonthEditField.Value;
elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
                app.MonthEditField.Value = month(d);
end   


latlong=importdata('Cities.txt').data;
idx_city = find(strcmp(app.CItyDropDown.Items, app.CItyDropDown.Value));


lat = latlong(idx_city,1);
longitude = latlong(idx_city,2);
timezone = latlong(idx_city,3);
            
if mod(app.YearEditField.Value, 4) == 0
    day_total = 366;
else
    day_total = 365;
end
hour = 0:23;
gamma = zeros(1,length(hour));
for i = 1:length(hour)
    gamma(i)=2.*pi/day_total.*(d-1+((hour(i)-12)./24));
end

eqtime = 229.18*(0.000075 + 0.001868*cos(gamma) - 0.032077*sin(gamma) - 0.014615*cos(2*gamma) - 0.040849*sin(2*gamma));
decl = 0.006918 - 0.399912*cos(gamma) + 0.070257*sin(gamma) - 0.006758*cos(2*gamma) + 0.000907*sin(2*gamma) - 0.002697*cos(3*gamma) + 0.00148*sin(3*gamma);

time_offset = eqtime + 4*longitude - 60*timezone;
tst = hour*60 + time_offset;
ha = (tst / 4) - 180;

zenith = acos(sind(lat).*sin(decl) + cosd(lat).*cos(decl).*cosd(ha));
zenith=rad2deg(zenith);
app.AngleEditField.Value = '4';
elevation = 90-zenith;
suninterval = find(elevation>0);
 app.TimeSlider.Limits = [suninterval(1)-1,suninterval(length(suninterval))-1];
 %app.TimeSlider.MajorTicks = (suninterval(1)-1:1:suninterval(length(suninterval))-1);
 %app.TimeSlider.MajorTickLabels = {suninterval(1)-1:1:suninterval(length(suninterval))-1};
app.AngleEditField.Value = '3';
 %[~,minIdx]=min(abs(value-event.Source.MajorTicks(:)));
 %           event.Source.Value = event.Source.MagorTicks(minIdx);
  %          app.TimeSlider.Value= event.Source.MajorTicks(minIdx);

            app.AngleEditField.Value = '2';

if (strcmpi(app.MovingDropDown.Value,'Movable'))
    app.AngleEditField.Value = '1';
    awningset = elevation - 90;
    idx_o_thing = round(app.TimeSlider.Value) +1;
    app.AngleEditField.Value = num2str(awningset(idx_o_thing))
    %i = event.Value;
    %app.AngleEditField.Value = num2str(awningset(i));      
elseif (strcmpi(app.MovingDropDown.Value, 'Stationary'))
    elevationavg = mean(elevation(suninterval(1):suninterval(length(suninterval))));
    awningset = elevationavg-90;
    app.AngleEditField.Value = num2str(awningset);
end
% plot

 angle = awningset(idx_o_thing);
 x = [0,cosd(angle)]
 y=[0,sind(angle)]
 %y = (sind(angle)-1)/(cosd(angle)-1)*x+2
%app.Gauge. = app.AngleEditField;
plot(app.UIAxes,x,y);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MovingDropDownLabel = uilabel(app.LeftPanel);
            app.MovingDropDownLabel.Text = 'Moving?';
            app.MovingDropDown = uidropdown(app.LeftPanel);
            app.MovingDropDown.Items = {'Movable', 'Stationary', ''};
            app.MovingDropDown.ValueChangedFcn = createCallbackFcn(app, @MovingDropDownValueChanged, true);
            app.MovingDropDown.Value = '';
            app.YearEditFieldLabel = uilabel(app.LeftPanel);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YearEditField.Limits = [0 Inf];
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @YearEditFieldValueChanged, true);
            app.YearEditField.Value = 2022;
            app.MonthEditFieldLabel = uilabel(app.LeftPanel);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthEditField.Limits = [1 12];
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.MonthEditField.Value = 1;
            app.DayEditFieldLabel = uilabel(app.LeftPanel);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DayEditField.Limits = [1 366];
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 1;
            app.CItyDropDownLabel = uilabel(app.LeftPanel);
            app.CItyDropDownLabel.Text = 'CIty';
            app.CItyDropDown = uidropdown(app.LeftPanel);
            app.CItyDropDown.Items = {};
            app.CItyDropDown.Editable = 'on';
            app.CItyDropDown.ValueChangedFcn = createCallbackFcn(app, @CItyDropDownValueChanged, true);
            app.CItyDropDown.Value = {};
            app.IdealTempFEditFieldLabel = uilabel(app.LeftPanel);
            app.IdealTempFEditFieldLabel.Text = 'Ideal Temp (F)';
            app.IdealTempFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IdealTempFEditField.ValueChangedFcn = createCallbackFcn(app, @IdealTempFEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 0];
            app.UIAxes.XTick = [0 20];
            app.UIAxes.XTickLabel = {'0'; '20'};
            app.UIAxes.YTick = [0 5];
            app.UIAxes.YTickLabel = {'0'; '5'};
            app.StartButton = uibutton(app.CenterPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TimeSliderLabel = uilabel(app.CenterPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.CenterPanel);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.AngleEditFieldLabel = uilabel(app.RightPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.RightPanel, 'text');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.ValueChangingFcn = createCallbackFcn(app, @AngleEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solar_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end